classdef Application_Measure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeLeftEditField              matlab.ui.control.EditField
        TimeLeftEditFieldLabel         matlab.ui.control.Label
        ZEApplication_MeasureV11Label  matlab.ui.control.Label
        ReadyLabel                     matlab.ui.control.Label
        STOPButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        SelectedExpirimentDropDown     matlab.ui.control.DropDown
        SelectedExpirimentDropDownLabel  matlab.ui.control.Label
        SelectedDurationHrsEditField   matlab.ui.control.NumericEditField
        SelectedDurationHrsEditFieldLabel  matlab.ui.control.Label
        SelectedIntervalMinEditField   matlab.ui.control.NumericEditField
        SelectedIntervalMinEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        StopFlag=false; % Description
        Timer;
    end
    
    methods (Access = private)
        
        function current = measureCurrentDC(app,obj)
        
            writeline(obj, "channel.setdmm('141:142', dmm.ATTR_MEAS_FUNCTION, dmm.FUNC_DC_CURRENT)");
            writeline(obj, "channel.setdmm('241:242', dmm.ATTR_MEAS_FUNCTION, dmm.FUNC_DC_CURRENT)");
            writeline(obj, "scan.create('141,142,241,242')")
            writeline(obj, "defbuffer1.capacity = 10 * 16")
            writeline(obj, "trigger.model.initiate()")
            writeline(obj, "waitcomplete()")
            responseString = writeread(obj, "printbuffer(1, defbuffer1.n, defbuffer1)");
            
            valuesStr = strsplit(responseString);
            current = str2double(valuesStr);
            
            overflowThreshold = NaN;
            current(current == overflowThreshold) = NaN;
            invalidIndices = isnan(current);
            current(invalidIndices) = NaN;
        
        end
        
        
        function current = measureCurrentAC(app,obj)
        
            writeline(obj, "channel.setdmm('141:142', dmm.ATTR_MEAS_FUNCTION, dmm.FUNC_AC_CURRENT)");
            writeline(obj, "channel.setdmm('241:242', dmm.ATTR_MEAS_FUNCTION, dmm.FUNC_AC_CURRENT)");
            writeline(obj, "scan.create('141,142,241,242')")
            writeline(obj, "defbuffer1.capacity = 10 * 16")
            writeline(obj, "trigger.model.initiate()")
            writeline(obj, "waitcomplete()")
            responseString = writeread(obj, "printbuffer(1, defbuffer1.n, defbuffer1)");
            
            valuesStr = strsplit(responseString);
            current = str2double(valuesStr);
            
            overflowThreshold = NaN;
            current(current == overflowThreshold) = NaN;
            invalidIndices = isnan(current);
            current(invalidIndices) = NaN;
        
        end
    
        
      % Measurement function
      function temperature = measureTemperature6510(app,obj)
                        
        writeline(obj, "channel.setdmm('101:140', dmm.ATTR_MEAS_FUNCTION, dmm.FUNC_TEMPERATURE)");
        writeline(obj, "channel.setdmm('101:140', dmm.ATTR_MEAS_TRANSDUCER, dmm.TRANS_THERMOCOUPLE)")
        writeline(obj, "channel.setdmm('101:140', dmm.ATTR_MEAS_THERMOCOUPLE, dmm.THERMOCOUPLE_K)")
        writeline(obj, "channel.setdmm('101:140', dmm.ATTR_MEAS_REF_JUNCTION, dmm.REFJUNCT_SIMULATED)")
        writeline(obj, "channel.setdmm('201:240', dmm.ATTR_MEAS_FUNCTION, dmm.FUNC_TEMPERATURE)");
        writeline(obj, "channel.setdmm('201:240', dmm.ATTR_MEAS_TRANSDUCER, dmm.TRANS_THERMOCOUPLE)")
        writeline(obj, "channel.setdmm('201:240', dmm.ATTR_MEAS_THERMOCOUPLE, dmm.THERMOCOUPLE_K)")
        writeline(obj, "channel.setdmm('201:240', dmm.ATTR_MEAS_REF_JUNCTION, dmm.REFJUNCT_SIMULATED)")
        writeline(obj, "scan.create('101:140, 201:240')")
        writeline(obj, "defbuffer1.capacity = 10 * 16")
        writeline(obj, "trigger.model.initiate()")
        writeline(obj, "waitcomplete()")
        responseString= writeread(obj, "printbuffer(1, defbuffer1.n, defbuffer1)");
                        
        valuesStr = strsplit(responseString);
        temperature = str2double(valuesStr);
                        
        overflowThreshold = 9.900000000e+37;
        temperature(temperature == overflowThreshold) = NaN;
        invalidIndices = isnan(temperature);
        temperature(invalidIndices) = NaN;
                        
        % Pad with NaNs to make the length divisible by 11
        remainder = mod(length(temperature), 11);
        if remainder > 0
            padding = 11 - remainder;
            for k = 1:padding
                temperature(end+1) = NaN;
             end
         end
    end
        
                        % Measurement function
    function temperature = measureTemperature3706(app,obj)
        writeline(obj, 'channel.open("allslots")');	  
        writeline(obj, 'reading_buffer = dmm.makebuffer(1000)');				
        writeline(obj, 'dmm.func = dmm.TEMPERATURE');					
        writeline(obj, 'dmm.nplc = 1');
        writeline(obj, 'dmm.transducer = dmm.TEMP_THERMOCOUPLE');			
        writeline(obj, 'dmm.refjunction = dmm.REF_JUNCTION_INTERNAL');	
        writeline(obj, 'dmm.thermocouple = dmm.THERMOCOUPLE_J');			
        writeline(obj, 'dmm.units = dmm.UNITS_CELSIUS');			
        writeline(obj, 'dmm.configure.set("mytemp")');
        writeline(obj, 'dmm.setconfig("4001:4040","mytemp")')	
        writeline(obj, 'scan.create("4001:4040")')
        writeline(obj, 'scan.execute(reading_buffer)')
        responseString1 = writeread(obj, 'printbuffer(1, reading_buffer.n, reading_buffer)');
        writeline(obj, 'dmm.setconfig("3001:3060","mytemp")')	
        writeline(obj, 'scan.create("3001:3060")')
        writeline(obj, 'scan.execute(reading_buffer)')
        responseString2 = writeread(obj, 'printbuffer(1, reading_buffer.n, reading_buffer)');
        writeline(obj, 'dmm.setconfig("6001:6040","mytemp")')
        writeline(obj, 'scan.create("6001:6040")')
        writeline(obj, 'scan.execute(reading_buffer)')
        responseString3 = writeread(obj, 'printbuffer(1, reading_buffer.n, reading_buffer)');
        responseString = append(responseString1, responseString2, responseString3);
                    
        valuesStr = strsplit(responseString);
        temperature = str2double(valuesStr);
                    
        overflowThreshold = 9.900000000e+37;
        temperature(temperature == overflowThreshold) = NaN;
        invalidIndices = isnan(temperature);
        temperature(invalidIndices) = NaN;
                    
         % Pad with NaNs to make the length divisible by 11
         remainder = mod(length(temperature), 11);
         if remainder > 0
            padding = 11 - remainder;
            for k = 1:padding
                temperature(end+1) = NaN;
             end
        end
    end
        
end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectedIntervalMinEditField
        function SelectedIntervalMinEditFieldValueChanged(app, event)
            value = app.SelectedIntervalMinEditField.Value;
           
        end

        % Value changed function: SelectedDurationHrsEditField
        function SelectedDurationHrsEditFieldValueChanged(app, event)
            value = app.SelectedDurationHrsEditField.Value;
        end

        % Value changed function: SelectedExpirimentDropDown
        function SelectedExpirimentDropDownValueChanged(app, event)
            value = app.SelectedExpirimentDropDown.Value;
        end

        % Clicked callback: SelectedExpirimentDropDown
        function SelectedExpirimentDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             switch app.SelectedExpirimentDropDown.Value
                case "Current_DC_6510"
                   % Connect to the Device and Setup Variables
                    clear obj;
                    name = visadevlist;
                    resourceName="KEITHLEY INSTRUMENTS";
                    [rows,~]=size(name);
                    for i=rows
                        if name{i,3}==resourceName
                            objName=name{i,1};
                        else
                            disp("No Keithley Device is Connected")
                        end
                    end
                    obj = visadev(objName);
                    delete("CurrentData_TEMPORARY.xlsx")
                    Currents = 4;
                    Intervalmin = app.SelectedIntervalMinEditField.Value;
                    Durationhrs = app.SelectedDurationHrsEditField.Value;
                    app.StopFlag=false;
                    app.ReadyLabel.Text="Running..";
                    app.ReadyLabel.BackgroundColor="yellow";
                    ChannelNames = {'141','142','241','242'};

                    % Instrument Setup
                    writeline(obj, "reset()")
                    
                    % Data Collection Loop
                    CurrentData = [];
                    timestamps = {}; 
                    startTime = tic;
                    while toc(startTime) < (Durationhrs * 3600) && ~app.StopFlag
                        timeLeft = (Durationhrs * 3600) - toc(startTime);
                        hoursLeft = floor(timeLeft / 3600);
                        minutesLeft = floor(mod(timeLeft, 3600) / 60);
                        secondsLeft = mod(timeLeft, 60);
                        hoursStr = num2str(hoursLeft);
                        minutesStr = num2str(minutesLeft);
                        secondsStr = num2str(secondsLeft);
                        timeLeftStr = [hoursStr, ':', minutesStr, ':', secondsStr];
                        app.TimeLeftEditField.Value = timeLeftStr;
                        x = datetime('now', 'Format', 'HH:mm:ss');
                        CurrentMeasurements = measureCurrentDC(app,obj);
                        
                        timestamps{end+1, 1} = x; 
                    
                        CurrentData = [CurrentData; CurrentMeasurements];
                        

                        headers = [{'Timestamp'}, ChannelNames];
                        % Write Headers
                        writecell(headers, "CurrentData_TEMPORARY.xlsx", 'Range', 'A1');
                    
                        % Write Timestamps
                        writecell(timestamps, "CurrentData_TEMPORARY.xlsx", 'Range', 'A2');
                    
                        % Write Current Data with timestamp in the filename
                        writematrix(CurrentData, "CurrentData_TEMPORARY.xlsx", 'Range', 'B2');
                        open("CurrentData_TEMPORARY.xlsx")
                        pause(Intervalmin * 60)
                    end
                    
                    
                    % Set Headers
                    headers = [{'Timestamp'}, ChannelNames];
                    filename = strcat("CurrentData", "_", datestr(now,'dd-mm-yyyy_HH.MM.SS'), '.xlsx');
                    % Write Headers
                    writecell(headers, filename, 'Range', 'A1');
                    
                    % Write Timestamps
                    writecell(timestamps, filename, 'Range', 'A2');
                    
                    
                    % Write Current Data with timestamp in the filename
                    writematrix(CurrentData, filename, 'Range', 'B2');
                    
                    
                    % Cleanup
                    delete(obj);
                    delete("CurrentData_TEMPORARY.xlsx")
                    app.ReadyLabel.Text="Ready";
                    app.ReadyLabel.BackgroundColor=[0.03,0.57,0.03];
                    app.TimeLeftEditField.Value= "0";


                case "Temperature_6510"
                    % Initialize parameters
                    clear obj;
                    name = visadevlist;
                    resourceName="KEITHLEY INSTRUMENTS";
                    [rows,~]=size(name);
                    for i=rows
                        if name{i,3}==resourceName
                            objName=name{i,1};
                        else
                            disp("No Keithley Device is Connected")
                        end
                    end
                    obj = visadev(objName);
                    delete("TempData_6510_TEMPORARY.fig")
                    delete("TempData_6510_TEMPORARY.xlsx")
                    obj.Timeout=20;
                    numTemps = 132;
                    Intervalmin = app.SelectedIntervalMinEditField.Value;
                    Durationhrs = app.SelectedDurationHrsEditField.Value;
                    pipeNames = {'CC1-', 'CIDS11-', 'CIDS21-', 'CIDS31-', 'CC2-', 'CIDS12-', 'CIDS22-', 'CIDS32-'};
                    numPipes = numel(pipeNames);
                    app.StopFlag=false;
                    app.ReadyLabel.Text="Running..";
                    app.ReadyLabel.BackgroundColor="yellow";
                    
                    % Generate point names
                    pointNames = cell(1, numTemps);
                    for n = 1:numPipes
                        for i = 1:11
                            pointNames{(n-1)*11 + i} = [pipeNames{n}, num2str(i)];
                        end
                    end
                    
                    % Initialize data storage
                    temperatureData = cell(1, 0);
                    timestamps = NaT(0, 1);
                    
                    % Instrument setup
                    writeline(obj, 'reset()');
                    
                    % Data collection loop
                    startTime = tic;
                    while toc(startTime) < (Durationhrs * 3600) && ~app.StopFlag
                        timeLeft = (Durationhrs * 3600) - toc(startTime);
                        hoursLeft = floor(timeLeft / 3600);
                        minutesLeft = floor(mod(timeLeft, 3600) / 60);
                        secondsLeft = mod(timeLeft, 60);
                        hoursStr = num2str(hoursLeft);
                        minutesStr = num2str(minutesLeft);
                        secondsStr = num2str(secondsLeft);
                        timeLeftStr = [hoursStr, ':', minutesStr, ':', secondsStr];
                        app.TimeLeftEditField.Value = timeLeftStr;
                        i = length(temperatureData) + 1;
                        x = datetime('now', 'Format', 'HH:mm:ss');
                        temperatureMeasurements = measureTemperature6510(app,obj);
                    
                        temperatureData{i} = temperatureMeasurements;
                        timestamps(i, 1) = x;
                        
                        minLength = min([length(pointNames), numel(temperatureData), length(timestamps)]);
                        numGraphs = ceil(numel(temperatureData) / 11);
                    
                        for graphIndex = 1:numGraphs
                            figure;
                            for n = 1:numPipes
                                subplot(3, 4, n);
                                hold on;
                                pipeIndices = (n-1)*11 + 1:n*11;
                    
                                for i = (graphIndex-1)*11 + 1 : min(graphIndex*11, minLength)
                                    currentTimestamp = timestamps(i);
                    
                                    if numel(temperatureData{i}) >= max(pipeIndices)
                                        color = hsv2rgb([n/numPipes, 1, 1]);
                                        scatter(repmat(currentTimestamp, 1, length(pipeIndices)), temperatureData{i}(pipeIndices),"MarkerEdgeColor",color,"Marker", '*', 'DisplayName', '');
                    
                                        for j = 1:length(pipeIndices)
                                            dataIndex = pipeIndices(j);
                    
                                            if dataIndex <= numTemps
                                                text(currentTimestamp, temperatureData{i}(dataIndex), [pointNames{dataIndex}, '', ''], 'FontSize', 8, 'HorizontalAlignment', 'right');
                                            end
                                        end
                                    end
                                end
                    
                                hold off;
                                xlabel('Timestamp');
                                ylabel('Temperature (Celsius)');
                                title(['Temperature Measurements - ', pipeNames{n}]);
                                grid on;
                            end
                            saveas(gcf,"TempData_6510_TEMPORARY.fig")
                            dataCell = cell(numel(temperatureData), 0);
                            
                            for i = 1:numel(temperatureData)
                                data = temperatureData{i};
                                tempCell = cell(11, numel(pipeNames));
                                for j = 1:numel(pipeNames)
                                    startIdx = (j - 1) * 11 + 1;
                                    endIdx = j * 11;
                                    tempCell(:, j) = num2cell(data(startIdx:endIdx));
                                end
                                dataCell{i} = tempCell;
                            end
                            
                            dataTable = table(timestamps, 'VariableNames', {'Timestamp'});
                            for i = 1:numel(pipeNames)
                                for j = 1:11
                                    varName = strcat(pipeNames{i}, num2str(j));
                                    dataColumn = nan(numel(temperatureData), 1);
                                    for k = 1:numel(temperatureData)
                                        dataColumn(k) = dataCell{k}{j, i};
                                    end
                                    dataTable.(varName) = dataColumn;
                                end
                            end
                            
                            filenameTEMP = strcat("TempData_6510_TEMPORARY",'.xlsx');
                            writetable(dataTable, filenameTEMP);
                            pause(Intervalmin * 60);
                            close(gcf);
                        end
                    end
                    
                    % Clean up
                    delete(obj);
                    app.TimeLeftEditField.Value= "0";
                    
                    % Write data to Excel file
                    
                    % Initialize cell array to store data
                    dataCell = cell(numel(temperatureData), 0);
                    
                    % Organize data into cell array with headers
                    for i = 1:numel(temperatureData)
                        data = temperatureData{i};
                        tempCell = cell(11, numel(pipeNames));
                        for j = 1:numel(pipeNames)
                            startIdx = (j - 1) * 11 + 1;
                            endIdx = j * 11;
                            tempCell(:, j) = num2cell(data(startIdx:endIdx));
                        end
                        dataCell{i} = tempCell;
                    end
                    
                    % Create a table to store the data
                    dataTable = table(timestamps, 'VariableNames', {'Timestamp'});
                    for i = 1:numel(pipeNames)
                        for j = 1:11
                            varName = strcat(pipeNames{i}, num2str(j));
                            dataColumn = nan(numel(temperatureData), 1);
                            for k = 1:numel(temperatureData)
                                dataColumn(k) = dataCell{k}{j, i};
                            end
                            dataTable.(varName) = dataColumn;
                        end
                    end
                    
                    % Write data to Excel file
                    filename = strcat("TempData", "_", datestr(now,'dd-mm-yyyy_HH.MM.SS'), '.xlsx');
                    writetable(dataTable, filename);


                    % Determine minimum length for plotting
                    minLength = min([length(pointNames), numel(temperatureData), length(timestamps)]);
                    numGraphs = ceil(numel(temperatureData) / 11);
                    
                    % Plotting loop
                    for graphIndex = 1:numGraphs
                        figure;
                        for n = 1:numPipes
                            subplot(3, 4, n);
                            hold on;
                            pipeIndices = (n-1)*11 + 1:n*11;
                    
                            for i = (graphIndex-1)*11 + 1 : min(graphIndex*11, minLength)
                                currentTimestamp = timestamps(i);
                    
                                if numel(temperatureData{i}) >= max(pipeIndices)
                                    color = hsv2rgb([n/numPipes, 1, 1]);
                                    scatter(repmat(currentTimestamp, 1, length(pipeIndices)), temperatureData{i}(pipeIndices),"MarkerEdgeColor",color,"Marker", '*', 'DisplayName', '');
                    
                                    for j = 1:length(pipeIndices)
                                        dataIndex = pipeIndices(j);
                    
                                        if dataIndex <= numTemps
                                            text(currentTimestamp, temperatureData{i}(dataIndex), [pointNames{dataIndex}, '', ''], 'FontSize', 8, 'HorizontalAlignment', 'right');
                                        end
                                    end
                                end
                            end
                    
                            hold off;
                            xlabel('Timestamp');
                            ylabel('Temperature (Celsius)');
                            title(['Temperature Measurements - ', pipeNames{n}]);
                            grid on;
                        end
                        saveas(gcf, strcat("TempGraph_", "_", datestr(now, 'dd-mm-yyyy_HH.MM.SS'), '.fig'));
                    end
                    %Deleting Temporary Data
                    delete("TempData_6510_TEMPORARY.fig")
                    delete("TempData_6510_TEMPORARY.xlsx")
                    app.ReadyLabel.Text="Ready";
                    app.ReadyLabel.BackgroundColor=[0.03,0.57,0.03];

                case "Temperature_3706"
                % Initialize parameters
                clear obj;
                name = visadevlist;
                resourceName="KEITHLEY INSTRUMENTS";
                [rows,~]=size(name);
                for i=rows
                    if name{i,3}==resourceName
                        objName=name{i,1};
                    else
                        disp("No Keithley Device is Connected")
                    end
                end
                obj = visadev(objName);
                delete("TempData_3706_TEMPORARY.fig")
                delete("TempData_3706_TEMPORARY.fig")
                obj.Timeout=20;
                numTemps = 132;
                Intervalmin = app.SelectedIntervalMinEditField.Value;
                Durationhrs = app.SelectedDurationHrsEditField.Value;
                app.StopFlag=false;
                app.ReadyLabel.Text="Running..";
                app.ReadyLabel.BackgroundColor="yellow";
                pipeNames = {'CC1-', 'CIDS11-', 'CIDS21-', 'CIDS31-', 'CC2-', 'CIDS12-', 'CIDS22-', 'CIDS32-', 'CC3-', 'CIDS13-', 'CIDS23-', 'CIDS33-'};
                numPipes = numel(pipeNames);
                
                % Generate point names
                pointNames = cell(1, numTemps);
                for n = 1:numPipes
                    for i = 1:11
                        pointNames{(n-1)*11 + i} = [pipeNames{n}, num2str(i)];
                    end
                end
                
                % Initialize data storage
                temperatureData = cell(1, 0);
                timestamps = NaT(0, 1);
                
                % Instrument setup
                writeline(obj, 'reset()');
                
                % Data collection loop
                startTime = tic;
                while toc(startTime) < (Durationhrs * 3600) && ~app.StopFlag
                   timeLeft = (Durationhrs * 3600) - toc(startTime);
                   hoursLeft = floor(timeLeft / 3600);
                   minutesLeft = floor(mod(timeLeft, 3600) / 60);
                   secondsLeft = mod(timeLeft, 60);
                   hoursStr = num2str(hoursLeft);
                   minutesStr = num2str(minutesLeft);
                   secondsStr = num2str(secondsLeft);
                   timeLeftStr = [hoursStr, ':', minutesStr, ':', secondsStr];
                   app.TimeLeftEditField.Value = timeLeftStr;
                    i = length(temperatureData) + 1;
                    x = datetime('now', 'Format', 'HH:mm:ss');
                    temperatureMeasurements = measureTemperature3706(app,obj);
                
                    temperatureData{i} = temperatureMeasurements;
                    timestamps(i, 1) = x;
                    
                    minLength = min([length(pointNames), numel(temperatureData), length(timestamps)]);
                    numGraphs = ceil(numel(temperatureData) / 11);
                
                    for graphIndex = 1:numGraphs
                        figure;
                        for n = 1:numPipes
                            subplot(3, 4, n);
                            hold on;
                            pipeIndices = (n-1)*11 + 1:n*11;
                
                            for i = (graphIndex-1)*11 + 1 : min(graphIndex*11, minLength)
                                currentTimestamp = timestamps(i);
                
                                if numel(temperatureData{i}) >= max(pipeIndices)
                                    color = hsv2rgb([n/numPipes, 1, 1]);
                                    scatter(repmat(currentTimestamp, 1, length(pipeIndices)), temperatureData{i}(pipeIndices),"MarkerEdgeColor",color,"Marker", '*', 'DisplayName', '');
                
                                    for j = 1:length(pipeIndices)
                                        dataIndex = pipeIndices(j);
                
                                        if dataIndex <= numTemps
                                            text(currentTimestamp, temperatureData{i}(dataIndex), [pointNames{dataIndex}, '', ''], 'FontSize', 8, 'HorizontalAlignment', 'right');
                                        end
                                    end
                                end
                            end
                
                            hold off;
                            xlabel('Timestamp');
                            ylabel('Temperature (Celsius)');
                            title(['Temperature Measurements - ', pipeNames{n}]);
                            grid on;
                        end
                        saveas(gcf,"TempData_3706_TEMPORARY.fig")
                        dataCell = cell(numel(temperatureData), 0);
                        
                        for i = 1:numel(temperatureData)
                            data = temperatureData{i};
                            tempCell = cell(11, numel(pipeNames));
                            for j = 1:numel(pipeNames)
                                startIdx = (j - 1) * 11 + 1;
                                endIdx = j * 11;
                                tempCell(:, j) = num2cell(data(startIdx:endIdx));
                            end
                            dataCell{i} = tempCell;
                        end
                        
                        dataTable = table(timestamps, 'VariableNames', {'Timestamp'});
                        for i = 1:numel(pipeNames)
                            for j = 1:11
                                varName = strcat(pipeNames{i}, num2str(j));
                                dataColumn = nan(numel(temperatureData), 1);
                                for k = 1:numel(temperatureData)
                                    dataColumn(k) = dataCell{k}{j, i};
                                end
                                dataTable.(varName) = dataColumn;
                            end
                        end
                        
                        filenameTEMP = strcat("TempData_3706_TEMPORARY",'.xlsx');
                        writetable(dataTable, filenameTEMP);
                        pause(Intervalmin * 60);
                        close(gcf);
                    end
                end
                
                
                % Clean up
                delete(obj);
                app.TimeLeftEditField.Value= "0";
                
                % Write data to Excel file
                
                % Initialize cell array to store data
                dataCell = cell(numel(temperatureData), 0);
                
                % Organize data into cell array with headers
                for i = 1:numel(temperatureData)
                    data = temperatureData{i};
                    tempCell = cell(11, numel(pipeNames));
                    for j = 1:numel(pipeNames)
                        startIdx = (j - 1) * 11 + 1;
                        endIdx = j * 11;
                        tempCell(:, j) = num2cell(data(startIdx:endIdx));
                    end
                    dataCell{i} = tempCell;
                end
                
                % Create a table to store the data
                dataTable = table(timestamps, 'VariableNames', {'Timestamp'});
                for i = 1:numel(pipeNames)
                    for j = 1:11
                        varName = strcat(pipeNames{i}, num2str(j));
                        dataColumn = nan(numel(temperatureData), 1);
                        for k = 1:numel(temperatureData)
                            dataColumn(k) = dataCell{k}{j, i};
                        end
                        dataTable.(varName) = dataColumn;
                    end
                end
                
                % Write data to Excel file
                filename = strcat("TempData", "_", datestr(now,'dd-mm-yyyy_HH.MM.SS'), '.xlsx');
                writetable(dataTable, filename);


                % Determine minimum length for plotting
                minLength = min([length(pointNames), numel(temperatureData), length(timestamps)]);
                numGraphs = ceil(numel(temperatureData) / 11);
                
                % Plotting loop
                for graphIndex = 1:numGraphs
                    figure;
                    for n = 1:numPipes
                        subplot(3, 4, n);
                        hold on;
                        pipeIndices = (n-1)*11 + 1:n*11;
                
                        for i = (graphIndex-1)*11 + 1 : min(graphIndex*11, minLength)
                            currentTimestamp = timestamps(i);
                
                            if numel(temperatureData{i}) >= max(pipeIndices)
                                color = hsv2rgb([n/numPipes, 1, 1]);
                                scatter(repmat(currentTimestamp, 1, length(pipeIndices)), temperatureData{i}(pipeIndices),"MarkerEdgeColor",color,"Marker", '*', 'DisplayName', '');
                
                                for j = 1:length(pipeIndices)
                                    dataIndex = pipeIndices(j);
                
                                    if dataIndex <= numTemps
                                        text(currentTimestamp, temperatureData{i}(dataIndex), [pointNames{dataIndex}, '', ''], 'FontSize', 8, 'HorizontalAlignment', 'right');
                                    end
                                end
                            end
                        end
                
                        hold off;
                        xlabel('Timestamp');
                        ylabel('Temperature (Celsius)');
                        title(['Temperature Measurements - ', pipeNames{n}]);
                        grid on;
                    end
                    saveas(gcf, strcat("TempGraph_", "_", datestr(now, 'dd-mm-yyyy_HH.MM.SS'), '.fig'));
                end
                %Deleting Temporary Data
                delete("TempData_3706_TEMPORARY.fig")
                delete("TempData_3706_TEMPORARY.xlsx")
                app.ReadyLabel.Text="Ready";
                app.ReadyLabel.BackgroundColor=[0.03,0.57,0.03]


                   case "Current_AC_6510"
                   % Connect to the Device and Setup Variables
                    clear obj;
                    name = visadevlist;
                    resourceName="KEITHLEY INSTRUMENTS";
                    [rows,~]=size(name);
                    for i=rows
                        if name{i,3}==resourceName
                            objName=name{i,1};
                        else
                            disp("No Keithley Device is Connected")
                        end
                    end
                    obj = visadev(objName);
                    delete("CurrentData_TEMPORARY.xlsx")
                    Currents = 4;
                    Intervalmin = app.SelectedIntervalMinEditField.Value;
                    Durationhrs = app.SelectedDurationHrsEditField.Value;
                    app.StopFlag=false;
                    app.ReadyLabel.Text="Running..";
                    app.ReadyLabel.BackgroundColor="yellow";
                    ChannelNames = {'141','142','241','242'};
                    

                    % Instrument Setup
                    writeline(obj, "reset()")
                    
                    % Data Collection Loop
                    CurrentData = [];
                    timestamps = {}; 
                    startTime = tic;
                    while toc(startTime) < (Durationhrs * 3600) && ~app.StopFlag
                        timeLeft = (Durationhrs * 3600) - toc(startTime);
                        hoursLeft = floor(timeLeft / 3600);
                        minutesLeft = floor(mod(timeLeft, 3600) / 60);
                        secondsLeft = mod(timeLeft, 60);
                        hoursStr = num2str(hoursLeft);
                        minutesStr = num2str(minutesLeft);
                        secondsStr = num2str(secondsLeft);
                        timeLeftStr = [hoursStr, ':', minutesStr, ':', secondsStr];
                        app.TimeLeftEditField.Value = timeLeftStr;
                        x = datetime('now', 'Format', 'HH:mm:ss');
                        CurrentMeasurements = measureCurrentAC(app,obj);
                        
                        timestamps{end+1, 1} = x; 
                    
                        CurrentData = [CurrentData; CurrentMeasurements];
                    
                        headers = [{'Timestamp'}, ChannelNames];
                        % Write Headers
                        writecell(headers, "CurrentData_TEMPORARY.xlsx", 'Range', 'A1');
                    
                        % Write Timestamps
                        writecell(timestamps, "CurrentData_TEMPORARY.xlsx", 'Range', 'A2');
                    
                    
                        % Write Current Data with timestamp in the filename
                        writematrix(CurrentData, "CurrentData_TEMPORARY.xlsx", 'Range', 'B2');
                        open("CurrentData_TEMPORARY.xlsx")
                        pause(Intervalmin * 60)
                        
                    end
                    
                    
                    % Set Headers
                    headers = [{'Timestamp'}, ChannelNames];
                    filename = strcat("CurrentData", "_", datestr(now,'dd-mm-yyyy_HH.MM.SS'), '.xlsx');
                    % Write Headers
                    writecell(headers, filename, 'Range', 'A1');
                    
                    % Write Timestamps
                    writecell(timestamps, filename, 'Range', 'A2');
                    
                    
                    % Write Current Data with timestamp in the filename
                    writematrix(CurrentData, filename, 'Range', 'B2');
                    
                    
                    % Cleanup
                    delete(obj);
                    delete("CurrentData_TEMPORARY.xlsx")
                    app.ReadyLabel.Text="Ready";
                    app.ReadyLabel.BackgroundColor=[0.03,0.57,0.03];
                    app.TimeLeftEditField.Value= "0";
                
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
         app.StopFlag=true;
         app.ReadyLabel.Text="Stopping....";
         app.ReadyLabel.BackgroundColor="red";
        end

        % Value changed function: TimeLeftEditField
        function TimeLeftEditFieldValueChanged(app, event)
            value = app.TimeLeftEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectedIntervalMinEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedIntervalMinEditFieldLabel.Text = 'Selected Interval Min';
            app.SelectedIntervalMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectedIntervalMinEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedIntervalMinEditFieldValueChanged, true);
            app.SelectedDurationHrsEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedDurationHrsEditFieldLabel.Text = 'Selected Duration Hrs';
            app.SelectedDurationHrsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectedDurationHrsEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedDurationHrsEditFieldValueChanged, true);
            app.SelectedExpirimentDropDownLabel = uilabel(app.UIFigure);
            app.SelectedExpirimentDropDownLabel.Text = 'Selected Expiriment';
            app.SelectedExpirimentDropDown = uidropdown(app.UIFigure);
            app.SelectedExpirimentDropDown.Items = {'Current_DC_6510', 'Current_AC_6510', 'Temperature_6510', 'Temperature_3706'};
            app.SelectedExpirimentDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedExpirimentDropDownValueChanged, true);
            app.SelectedExpirimentDropDown.ClickedFcn = createCallbackFcn(app, @SelectedExpirimentDropDownClicked, true);
            app.SelectedExpirimentDropDown.Value = 'Current_DC_6510';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Text = 'Ready';
            app.ZEApplication_MeasureV11Label = uilabel(app.UIFigure);
            app.ZEApplication_MeasureV11Label.Text = 'ZE-Application_Measure V.1.1';
            app.TimeLeftEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLeftEditFieldLabel.Text = 'Time Left';
            app.TimeLeftEditField = uieditfield(app.UIFigure, 'text');
            app.TimeLeftEditField.ValueChangedFcn = createCallbackFcn(app, @TimeLeftEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application_Measure

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end